classdef CeuL10_SharpeningLinearQuadraticApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Alpha1willyieldthehighestsharpeningoftheimageLabel  matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label             matlab.ui.control.Label
        AlphaSlider       matlab.ui.control.Slider
        AlphaSliderLabel  matlab.ui.control.Label
        ImageLabel        matlab.ui.control.Label
        ExecuteButton     matlab.ui.control.Button
        SharpeningLabel   matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            %JORGE
            tam=size(I);
            row=tam(1);
            col=tam(2);
            mask = ones(row,col);
            centerR = floor(row/2)+1;
            centerC = floor(col/2)+1;
            for i = centerR-20:centerR+20
                for j = centerC-20:centerC+20
                     mask(i,j) = 0;
                 end
            end
            c = fft2(I);
            wx = (-row/2:row/2-1)/row;
            wy = (-col/2:col/2-1)/col;
            cLinear = c.* sqrt(pow2(wx)+pow2(wy));
            cCuadratic = c.*pow2(wx)+pow2(wy);
            d = fftshift(mask);
            eLinear = cLinear.*d;
            eCuadratic = cCuadratic.*d;
            fLinear = abs(ifft2(eLinear));
            fCuadratic = abs(ifft2(eCuadratic));
            finalLinear = uint8(fLinear)*app.AlphaSlider.Value+I;
            finalCuadratic = uint8(fCuadratic)*app.AlphaSlider.Value+I;
            
            figure();
            subplot(1,3,1);
            imshow(I),title('original');
            subplot(1,3,2);
            imshow(finalLinear),title('sharpened')
            subplot(1,3,3);
            imshow(finalCuadratic),title('sharpened')


            %First we compute the fourier transform
            
            %sizeOfImage = size(F);
            %linear
            %if app.LinearButton.Value
             %   for u = 1:sizeOfImage(1)
              %      for v=1:sizeOfImage(2)
               %         F(u,v) = (sqrt(pow2(u)+pow2(v))*F(u,v));
                %    end
                %end
                %figure('Name',strcat(windowName,"Linear Sharpening"));
                %Real = uint8(real(F));
                %Final = Real + I;
                %imshow(Final);
            %end
            %Show image 
%             if(app.CuadraticButton.Value)
%                 for u = 1:sizeOfImage(1)
%                     for v=1:sizeOfImage(2)
%                         F(u,v) = (sqrt(pow2(u)+pow2(v))*F(u,v) ) + I(u,v);
%                     end
%                 end
%             end


            %JORGE
            
%             if length(size(I))==3
%                 R = I(:,:,1);
%                 G = I(:,:,2);
%                 B = I(:,:,3);
%                 hr = figure('Name',strcat(windowName," (red)"));
%                 imshow(R);
%                 hg = figure('Name',strcat(windowName," (green)"));
%                 imshow(G);
%                 hb = figure('Name',strcat(windowName," (blue)"));
%                 imshow(B);
%                 if app.LinearButton.Value
%                     z = zeros(256,1);
%                     l = ([0:255]')/255;
%                     figure(hr);
%                     colormap([l z z]);
%                     figure(hg);
%                     colormap([z l z]);
%                     figure(hb);
%                     colormap([z z l]);                    
%                 end
%                 app.parentApp.updateImageList();
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SharpeningLabel = uilabel(app.UIFigure);
            app.SharpeningLabel.Text = 'Sharpening';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [0 1];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'These components are multiplied by alpha and sumed to the original image, so with alpha = 0 ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'In order to sharpen the image, the high frequency components are found using a HP filter. ';
            app.Alpha1willyieldthehighestsharpeningoftheimageLabel = uilabel(app.UIFigure);
            app.Alpha1willyieldthehighestsharpeningoftheimageLabel.Text = 'the original image will be obtained. Alpha = 1 will yield the highest sharpening of the image.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL10_SharpeningLinearQuadraticApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end