classdef CeuL15_MultipleNoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ComputemultiplenoisesLabel  matlab.ui.control.Label
        ExecuteButton               matlab.ui.control.Button
        ImageLabel                  matlab.ui.control.Label
        TipoderuidoButtonGroup      matlab.ui.container.ButtonGroup
        GaussianoButton             matlab.ui.control.RadioButton
        saltpepperButton            matlab.ui.control.RadioButton
        speckleButton               matlab.ui.control.RadioButton
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            J1=rgb2gray(im2double(I));
            c1 = zeros(size(I(:,:,1))*2-1);%for finding the autocorrelation
            %pxx1 = zeros(257, 375);%for the pds
            pxx1 = abs(fft2(fftshift(J1)))^2;
            if app.GaussianoButton.Value
                user_noise = "gaussian";
            end
            if app.saltpepperButton.Value
                user_noise = "salt & pepper";
            end
            if app.speckleButton.Value
                user_noise = "speckle";
            end
            for i=1:1:10
                J1=imnoise(J1,user_noise);%ruido gaussiano
                c1 = c1 + xcorr2(J1);%for finding the autocorrelation
                pxx1 = pxx1 + abs(fft2(fftshift(J1)))^2;%for the pds
            end
                hr = figure('Name',strcat(windowName, user_noise));
                subplot(1,2,1)
                imshow(J1);
                title("Image with " + user_noise);
                subplot(1,2,2)
                histogram(J1)
                title("Image Histogram");
                hf = figure('Name',strcat(windowName, "Autocorrelation" ));
                surf(log10(c1))
                colormap(hf,'hot');
                title("Image Autocorrelation");
                hd = figure('Name',strcat(windowName, "PDS"));
                surf(log10(pxx1))
                colormap(hd,'autumn');
                title("Image PDS");
                %[counts1,binLocations1] = imhist(J1);%para sacar los histogramas
                app.parentApp.updateImageList();
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.ComputemultiplenoisesLabel = uilabel(app.UIFigure);
            app.ComputemultiplenoisesLabel.Text = 'Compute multiple noises';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.TipoderuidoButtonGroup = uibuttongroup(app.UIFigure);
            app.TipoderuidoButtonGroup.Title = 'Tipo de ruido';
            app.GaussianoButton = uiradiobutton(app.TipoderuidoButtonGroup);
            app.GaussianoButton.Text = 'Gaussiano';
            app.GaussianoButton.Value = true;
            app.saltpepperButton = uiradiobutton(app.TipoderuidoButtonGroup);
            app.saltpepperButton.Text = 'salt & pepper';
            app.speckleButton = uiradiobutton(app.TipoderuidoButtonGroup);
            app.speckleButton.Text = 'speckle';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CeuL15_MultipleNoiseApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end