classdef CeuL2_JPEG100vs0qualityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        JPEG100vs0qualityLabel  matlab.ui.control.Label
        ExecuteButton           matlab.ui.control.Button
        ImageLabel              matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            
            imwrite(I,'Image100quality.jpg','jpg','quality',100);
            imwrite(I,'Image0quality.jpg','jpg','quality',0);
            I_100quality = imread("Image100quality.jpg");
            I_0quality = imread("Image0quality.jpg");
            
            comparison = abs(I_100quality - I_0quality);
            comparison = double(comparison);
            I_comparison = comparison; %log(comparison+1);
            I_comparison = uint8(255*I_comparison/max(I_comparison(:)));
            
            name1 = sprintf(" with 100 quality");
            name2 = sprintf(" with 0 quality");
            windowName_1 = strcat(windowName,name1);
            windowName_2 = strcat(windowName,name2);
            windowName_3 = "comparison between 0 and 100"; 
            
            app.h.Name = windowName_1;
            image(I_100quality);
            figure('Name',windowName_2);
            image(I_0quality);
            figure('Name',windowName_3);
            image(I_comparison);
                
            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JPEG100vs0qualityLabel = uilabel(app.UIFigure);
            app.JPEG100vs0qualityLabel.Text = 'JPEG 100 vs 0 quality';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL2_JPEG100vs0qualityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end