classdef CeuL4_ComputeDifferencesBetweenImgsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadimageMenu                 matlab.ui.container.Menu
        UpdateimagelistMenu           matlab.ui.container.Menu
        QuitMenu                      matlab.ui.container.Menu
        DirectButton                  matlab.ui.control.Button
        AbsoluteButton                matlab.ui.control.Button
        LogarithmicButton             matlab.ui.control.Button
        CheckBox                      matlab.ui.control.CheckBox
        QuadraticButton               matlab.ui.control.Button
        SelectimageDropDown           matlab.ui.control.DropDown
        SelectimageDropDownLabel      matlab.ui.control.Label
        IJLabel                       matlab.ui.control.Label
        DirectmMmLabel                matlab.ui.control.Label
        logDirect1maxlogDirect1Label  matlab.ui.control.Label
        Direct2Label                  matlab.ui.control.Label
        PictureImage                  matlab.ui.control.Image
        PictureLabel                  matlab.ui.control.Label
        LoadanotherimageLabel         matlab.ui.control.Label
        ChecktomaxdynamicrangeLabel   matlab.ui.control.Label
    end

     % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = public)
        function updateImageList(app)
            figHandles = findobj('Type', 'figure');
            if length(figHandles)>0
                app.SelectimageDropDown.Items = {};
                for i=1:length(figHandles)
                    app.SelectimageDropDown.Items{i} = figHandles(i).Name;
                end
            end
        end
    end
    
    methods (Access = public)
        function h2 = getFigureHandler(app)
            h2= app.getFigureHandlerByName(app.SelectimageDropDown.Value);
        end
    end
    
    methods (Access = public)
        
        function h2 = getFigureHandlerByName(app, selectedName)
            figHandles = findobj('Type', 'figure');
            for i=1:length(figHandles)
                if strcmp(figHandles(i).Name,selectedName)
                    h2=figHandles(i);
                    return
                end
            end
            h2 = -1;
            return            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;            
            app.updateImageList();
            im = getimage(app.h); 
            app.PictureImage.ImageSource = im;
        end

        % Button pushed function: DirectButton
        function DirectButtonPushed(app, event)
             I = getimage(app.h);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = (I-J);
             if app.CheckBox.Value == 1
                 D = D*255;
             end
             app.PictureImage.ImageSource = D;
             app.PictureLabel.Text='DIRECT DIFFERENCE';
        end

        % Callback function: AbsoluteButton, SelectimageDropDown
        function AbsoluteButtonPushed(app, event)
             I = getimage(app.h);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = (I-J); 
             m= min(I(:));
             M = max(I(:));
             Ab=uint8(255*(D-m)/(M-m));
             Ab2 = imabsdiff(I,J) %las operaciones de arriba muestran la imagen en negro...
             if app.CheckBox.Value == 1
                 Ab2 = Ab2*255;
             end
             app.PictureImage.ImageSource = Ab2;
             app.PictureLabel.Text='ABSOLUTE DIFFERENCE';
        end

        % Button pushed function: LogarithmicButton
        function LogarithmicButtonPushed(app, event)
             I = getimage(app.h);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = double(abs(I-J));
             L=log(D+1);
             Lg = uint8(255*L/max(L(:)));
             app.PictureImage.ImageSource = Lg;
             app.PictureLabel.Text='LOGARITHMIC DIFFERENCE';
        end

        % Button pushed function: QuadraticButton
        function QuadraticButtonPushed(app, event)
             I = getimage(app.h);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = abs(I-J);
             Cr = uint8(D.^2);
             if app.CheckBox.Value == 1
                 Cr = Cr*255;
             end
             app.PictureImage.ImageSource = Cr;
             app.PictureLabel.Text='QUADRATIC DIFFERENCE';
        end

        % Menu selected function: LoadimageMenu
        function LoadimageMenuSelected(app, event)
               [file,path] = uigetfile('*.*','Select an Image');
               if ~isequal(file,0)
               fn=fullfile(path,file);
               [I,map] = imread(fn);
               figure('Name',file);
               imshow(I,map);
               app.SelectimageDropDown.Enable = 'on';
               app.DirectButton.Enable = 'on';
               app.AbsoluteButton.Enable = 'on';
               app.QuadraticButton.Enable = 'on';
               app.LogarithmicButton.Enable = 'on';
               app.LoadanotherimageLabel.Visible = 'off';
               app.updateImageList();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadimageMenu = uimenu(app.FileMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageMenuSelected, true);
            app.LoadimageMenu.Text = 'Load image';
            app.UpdateimagelistMenu = uimenu(app.FileMenu);
            app.UpdateimagelistMenu.Text = 'Update image list';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.DirectButton = uibutton(app.UIFigure, 'push');
            app.DirectButton.ButtonPushedFcn = createCallbackFcn(app, @DirectButtonPushed, true);
            app.DirectButton.Enable = 'off';
            app.DirectButton.Text = 'Direct';
            app.AbsoluteButton = uibutton(app.UIFigure, 'push');
            app.AbsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteButtonPushed, true);
            app.AbsoluteButton.Enable = 'off';
            app.AbsoluteButton.Text = 'Absolute';
            app.LogarithmicButton = uibutton(app.UIFigure, 'push');
            app.LogarithmicButton.ButtonPushedFcn = createCallbackFcn(app, @LogarithmicButtonPushed, true);
            app.LogarithmicButton.Enable = 'off';
            app.LogarithmicButton.Text = 'Logarithmic';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = {'0-255'; ''};
            app.QuadraticButton = uibutton(app.UIFigure, 'push');
            app.QuadraticButton.ButtonPushedFcn = createCallbackFcn(app, @QuadraticButtonPushed, true);
            app.QuadraticButton.Enable = 'off';
            app.QuadraticButton.Text = 'Quadratic';
            app.SelectimageDropDown = uidropdown(app.UIFigure);
            app.SelectimageDropDown.Items = {};
            app.SelectimageDropDown.ValueChangedFcn = createCallbackFcn(app, @AbsoluteButtonPushed, true);
            app.SelectimageDropDown.Enable = 'off';
            app.SelectimageDropDown.Value = {};
            app.SelectimageDropDownLabel = uilabel(app.UIFigure);
            app.SelectimageDropDownLabel.Text = {'Select image to '; 'compute difference with'};
            app.IJLabel = uilabel(app.UIFigure);
            app.IJLabel.Text = '(I-J)';
            app.DirectmMmLabel = uilabel(app.UIFigure);
            app.DirectmMmLabel.Text = '(Direct-m)/(M-m)';
            app.logDirect1maxlogDirect1Label = uilabel(app.UIFigure);
            app.logDirect1maxlogDirect1Label.Text = '(log(Direct+1))/max(log(Direct+1)(:))';
            app.Direct2Label = uilabel(app.UIFigure);
            app.Direct2Label.Text = '(Direct.^2)';
            app.PictureImage = uiimage(app.UIFigure);
            app.PictureLabel = uilabel(app.UIFigure);
            app.PictureLabel.Text = 'ORIGINAL IMAGE';
            app.LoadanotherimageLabel = uilabel(app.UIFigure);
            app.LoadanotherimageLabel.Text = 'Load another image!';
            app.ChecktomaxdynamicrangeLabel = uilabel(app.UIFigure);
            app.ChecktomaxdynamicrangeLabel.Text = {'Check to max'; 'dynamic range'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_ComputeDifferencesBetweenImgsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end