classdef CeuL6_ColorCubeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ColorCubeandIntersectionrgbcLabel  matlab.ui.control.Label
        VISUALIZEButton            matlab.ui.control.Button
        IntroducevalueofcLabel     matlab.ui.control.Label
        IntroducevaluesofrgbLabel  matlab.ui.control.Label
        VISUALIZEButton_2          matlab.ui.control.Button
        EditField                  matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_5                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
    end

    % Author: Alejandra Aceves Arranz   
    
    properties (Access = private)
        parentApp % parent app
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
 
        function  showColorCube(app)
           
        % to specify the different faces of the cubbe (6 faces)
        
        
            r1=[0 255 255 0];
            g1=[0 0 0 0];
            b1=[0 0 255 255];
            
           
            r2=[255 255 255 255];
            g2=[255 0 0 255];
            b2=[0 0 255 255];
            
          
            r3=[0 255 255 0];
            g3=[255 255 255 255];
            b3=[0 0 255 255];
            
            r4=[255 255 0 0];
            g4=[255 0 0 255];
            b4=[255 255 255 255];
            
            r5=[255 255 0 0];
            g5=[255 0 0 255];
            b5=[0 0 0 0];
            
            r6=[0 0 0 0];
            g6=[255 0 0 255];
            b6=[0 0 255 255];


          % There are common vertices for creating the faces and the color
          % associated to each vertex.
        
      
            c=zeros(1,4,3);
            c(:,1,:)=[0,0,0]; %black
            c(:,2,:)=[255,0,0]; %red
            c(:,3,:)=[255,0,255]; %magenta
            c(:,4,:)=[0,0,255]; %blue


             c1=zeros(1,4,3);
             c1(:,1,:)=[255,255,0]; %yellow
             c1(:,2,:)=[255,0,0]; %red
             c1(:,3,:)=[255,0,255]; %m
             c1(:,4,:)=[255,255,255]; %white
             
             c2=zeros(1,4,3);
             c2(:,1,:)=[0,255,0]; %green
             c2(:,2,:)=[255,255,0]; %yellow
             c2(:,3,:)=[255,255,255]; %white
             c2(:,4,:)=[0,255,255]; %cyan
             
             c3=zeros(1,4,3);
             c3(:,1,:)=[255,255,255]; %white
             c3(:,2,:)=[255,0,255]; %magenta
             c3(:,3,:)=[0,0,255]; %blue
             c3(:,4,:)=[0,255,255]; %cyan
             
             c4=zeros(1,4,3);
             c4(:,1,:)=[255,255,0]; %yellow
             c4(:,2,:)=[255,0,0]; %red
             c4(:,3,:)=[0,0,0]; %black
             c4(:,4,:)=[0,255,0]; %green
             
             c5=zeros(1,4,3);
             c5(:,1,:)=[0,0,255]; %blue
             c5(:,2,:)=[0,0,0]; %black
             c5(:,3,:)=[0,255,0]; %green
             c5(:,4,:)=[0,255,255]; %cyan

             
            % create a graph in 3D, specifying the color for each face
            fill3(r1,g1,b1,c,r2,g2,b2,c1,r3,g3,b3,c2,r4,g4,b4,c3,r5,g5,b5,c4,r6,b6,g6,c5);


            % Labels to show in the cube
            xlabel('R');
            ylabel('G');
            zlabel('B');
            title('Cubo RGB');
            axis equal;
            axis on; 
            rotate3d on; % to enable the rotation of the cube
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
            app.showColorCube();
    
        end

        % Button pushed function: VISUALIZEButton
        function VISUALIZEButtonPushed(app, event)
           
            %divide by 3 the value of C
            c_field=app.EditField.Value;
            C_usuario=c_field/3;
            C=C_usuario/255;% to make it fit between 0 and 1 for the function used


            % Define vertices
            vertices = [
                C, 0, 0;  % vertice #1
                0, C, 0;  % vertice #2
                0, 0, C]; % vertice #3

            % Define faces
            faces = [
                1, 2, 3]; % face #1

            % Draw patch
            figure;
            patch('Faces', faces, 'Vertices', vertices,'FaceColor',[C,C,C]);
            

            % Axes settings
            xlabel('R'); ylabel('G'); zlabel('B');
            axis vis3d equal;
            grid on;
            rotate3d on
           
            
        end

        % Button pushed function: VISUALIZEButton_2
        function VISUALIZEButton_2Pushed(app, event)
                
            % to make it fit between 0 and 1 for the function used
            r=app.EditField_5.Value/255;
            g=app.EditField_3.Value/255;
            b=app.EditField_6.Value/255;
 
            % Define vertices
            vertices = [
                r, 0, 0;  % vertice #1
                0, g, 0;  % vertice #2
                0, 0, b]; % vertice #3

            % Define faces
            faces = [
                1, 2, 3]; 

            % Draw patch
            figure;
            patch('Faces', faces, 'Vertices', vertices,'FaceColor',[r,g,b]);
            

            % Axes settings
            xlabel('R'); ylabel('G'); zlabel('B');
            axis vis3d equal;
            grid on;
            rotate3d on;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorCubeandIntersectionrgbcLabel = uilabel(app.UIFigure);
            app.ColorCubeandIntersectionrgbcLabel.Text = 'Color Cube and Intersection: r+g+b=c';
            app.VISUALIZEButton = uibutton(app.UIFigure, 'push');
            app.VISUALIZEButton.ButtonPushedFcn = createCallbackFcn(app, @VISUALIZEButtonPushed, true);
            app.VISUALIZEButton.Text = {'VISUALIZE'; ''};
            app.IntroducevalueofcLabel = uilabel(app.UIFigure);
            app.IntroducevalueofcLabel.Text = 'Introduce value of c:';
            app.IntroducevaluesofrgbLabel = uilabel(app.UIFigure);
            app.IntroducevaluesofrgbLabel.Text = {'Introduce values of r, g, b:'; ''};
            app.VISUALIZEButton_2 = uibutton(app.UIFigure, 'push');
            app.VISUALIZEButton_2.ButtonPushedFcn = createCallbackFcn(app, @VISUALIZEButton_2Pushed, true);
            app.VISUALIZEButton_2.Text = {'VISUALIZE'; ''};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL6_ColorCubeApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end