classdef CeuL7_PlotFTImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        SelectyourchoicePanel  matlab.ui.container.Panel
        AmplitudeCheckBox      matlab.ui.control.CheckBox
        RealpartCheckBox       matlab.ui.control.CheckBox
        ImaginarypartCheckBox  matlab.ui.control.CheckBox
        PhaseCheckBox          matlab.ui.control.CheckBox
        PSDCheckBox            matlab.ui.control.CheckBox
        ExecuteButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
       parentApp
       h
        
    end
    
    methods (Access = public)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp= parentApp;
            app.h=h;
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            gray = rgb2gray(I);
            
            if app.AmplitudeCheckBox.Value
                FT=fftshift(fft2(gray));
                % N=length(gray);
                %freq=0:(2*pi)/length(FT):pi;
                figure
                subplot(2,1,1);
                plot(abs(FT));
                title('Amplitude of an image');
                %xlabel('Normalized Frequency(\times\pi rad/sample)');
                subplot(2,1,2);
                imshow(gray);
                title('Original image');
                 
            end
            if app.RealpartCheckBox.Value
                FT=fftshift(fft2(gray));
                fl = log(1 + abs(FT));
                FT = fl/max(fl(:));
                
                %lo pongo en log para que se vea algo
%                 L=log(1+E);
%                 L1=mat2gray(L);
%                 L2=im2uint8(L1);
%                 LT=log(1+double(ET));
%                 L1T=mat2gray(LT);
%                 L2T=im2uint8(L1T);

                figure
                subplot(2,1,1);
                imshow(real(FT))
                title('Real part of the FFT shifted');
                subplot(2,1,2);
                imshow(gray);
                title('Original image');
                
            end
            if app.ImaginarypartCheckBox.Value
                FT = fftshift(fft2(gray));
                fl = log(1 + abs(FT));
                FT = fl/max(fl(:));
                %lo pongo en log para que se vea algo
%                 L=log(1+E);
%                 L1=mat2gray(L);
%                 L2=im2uint8(L1);
%                 LT=log(1+double(ET));
%                 L1T=mat2gray(LT);
%                 L2T=im2uint8(L1T);
                figure
                subplot(2,1,1);
                imshow(imag(FT));
                title('Imaginary part of the FFT shifted');
                subplot(2,1,2);
                imshow(gray);
                title('Original image');
                
            end
            if app.PSDCheckBox.Value
                %FT = fftshift(fft2(gray));
%                 p2=imagesc( log10(abs(FT).^2 ));
                psd = 10*log10(abs(fftshift(fft2(gray))).^2 );
                figure
                subplot(2,1,1);
                imshow(uint8(psd))
                title('Power density spectrum of an image');
                subplot(2,1,2);
                imshow(gray);
                title('Original image');
            end
            if app.PhaseCheckBox.Value


                fftA= fft2(double(gray));
                figure;
                subplot(2,1,1);
                imshow(angle(fftshift(fftA)));
                axis tight;   
                title('Phase of an image');
                subplot(2,1,2);
                imshow(gray);
                title('Original image');
                
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Plot the Fourier Transform of an image ';
            app.SelectyourchoicePanel = uipanel(app.UIFigure);
            app.SelectyourchoicePanel.Title = 'Select your choice';
            app.AmplitudeCheckBox = uicheckbox(app.SelectyourchoicePanel);
            app.AmplitudeCheckBox.Text = 'Amplitude';
            app.RealpartCheckBox = uicheckbox(app.SelectyourchoicePanel);
            app.RealpartCheckBox.Text = 'Real part';
            app.ImaginarypartCheckBox = uicheckbox(app.SelectyourchoicePanel);
            app.ImaginarypartCheckBox.Text = 'Imaginary part';
            app.PhaseCheckBox = uicheckbox(app.SelectyourchoicePanel);
            app.PhaseCheckBox.Text = 'Phase';
            app.PSDCheckBox = uicheckbox(app.SelectyourchoicePanel);
            app.PSDCheckBox.Text = 'PSD';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL7_PlotFTImage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end