classdef CeuL9_GaussianFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FilteredImage              matlab.ui.control.Image
        OriginalImage              matlab.ui.control.Image
        FilterPowerSpectrum        matlab.ui.control.Image
        GaussianFilterG2Label      matlab.ui.control.Label
        OriginalimageLabel         matlab.ui.control.Label
        FilterPowerSpectrumLabel   matlab.ui.control.Label
        FilteredimageLabel         matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        FiltertypeTab              matlab.ui.container.Tab
        SelectTypeButtonGroup      matlab.ui.container.ButtonGroup
        HighpassButton             matlab.ui.control.RadioButton
        BandpassButton             matlab.ui.control.RadioButton
        LowpassButton              matlab.ui.control.RadioButton
        ParametersTab              matlab.ui.container.Tab
        sigmaSpinnerLabel          matlab.ui.control.Label
        sigmaSpinner               matlab.ui.control.Spinner
        LowerEditFieldLabel        matlab.ui.control.Label
        LowerEditField             matlab.ui.control.NumericEditField
        HigherEditFieldLabel       matlab.ui.control.Label
        HigherEditField            matlab.ui.control.NumericEditField
        CutofffrequenciesLabel     matlab.ui.control.Label
        channelSpinnerLabel        matlab.ui.control.Label
        channelSpinner             matlab.ui.control.Spinner
        PSDTab                     matlab.ui.container.Tab
        TypeButtonGroup            matlab.ui.container.ButtonGroup
        AbsoluteButton             matlab.ui.control.RadioButton
        LogarithmicButton          matlab.ui.control.RadioButton
        ModulePhaseTab             matlab.ui.container.Tab
        ModuleRep                  matlab.ui.container.ButtonGroup
        LinearModule               matlab.ui.control.RadioButton
        LogarithmicModule          matlab.ui.control.RadioButton
        PhaseRep                   matlab.ui.container.ButtonGroup
        LinearPhase                matlab.ui.control.RadioButton
        LogarithmicPhase           matlab.ui.control.RadioButton
        ExporttootherwindowButton  matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        ModuleH                    matlab.ui.control.UIAxes
        PhaseH                     matlab.ui.control.UIAxes
    end

    % Author: Aïcha Díez de Benito
    
    properties (Access = private)
        parentApp
        h   %figure handler
        sigma  % filter size parameter 
        filt
        gaussian
        wlow        %cutting frequencies
        whi
        channel
        I % original image
        FTfilter % filter PSD 
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;  % Image handler
            app.UIFigure.Name = "Lecture 09. Gaussian filter App";
            app.OriginalImage.ImageSource = app.h.Name;
            
        end

        % Selection changed function: SelectTypeButtonGroup
        function TypeFilterSelectionChanged(app, event)
            
        %values to be used from parameters
            app.sigma  = app.sigmaSpinner.Value; 
            app.wlow = app.LowerEditField.Value;
            app.whi = app.LowerEditField.Value;
            app.channel = app.channelSpinner.Value;
            app.I = getimage(app.h);
            
            if length(size(app.I))==3
                G = rgb2gray(app.I);
            else
                G = app.I;
            end
            f = double(G);
            fft = fft2(f); % compute FFT of the grey image
            F = fftshift(fft); % centered fft of original image
            
            [M, N] = size(F);
            X = 0 : N-1;
            Y = 0 : M-1;
            [X, Y] = meshgrid(X, Y);
            deltaX = 0.5 * M;
            deltaY = 0.5 * N;
            
            app.sigma  = app.sigmaSpinner.Value;
            distance = (X - deltaX).^2 + (Y - deltaY).^2;
            app.gaussian = exp(-(distance) ./ (2 *app.sigma).^2); % gaussian function formula
            
            if app.LowpassButton.Value
                    Low = imgaussfilt(uint8(f), app.sigma);
                  app.FilteredImage.ImageSource = repmat(Low, 1, 1, 3);
                  
                  Low_filt = app.gaussian;
                LPFlinear = Low_filt(app.channel, 1:size(Low_filt,2));
                
                app.FTfilter = fftshift(fft2(LPFlinear)); % for PSD 
                plot(app.ModuleH, abs(app.FTfilter));
                plot(app.PhaseH, angle(app.FTfilter));
            end
            
            if app.HighpassButton.Value 
    
                    Hi_filt = 1-app.gaussian; % filter in real domain
                    High = ifft2(ifftshift(Hi_filt.*F));
                    
                app.FilteredImage.ImageSource = repmat(uint8(real(High)), 1, 1, 3);
                
                HPFlinear = Hi_filt(app.channel, 1:size(Hi_filt,2));
                app.FTfilter = fftshift(fft2(HPFlinear)); % for PSD 
                plot(app.ModuleH, abs(app.FTfilter));
                plot(app.PhaseH, angle(app.FTfilter));

            end
            
            if app.BandpassButton.Value
                bpf = double(G);
                [m, n] = size(bpf);
                bpf = uint8(bpf);
                fftI = fft2(bpf, 2*m-1, 2*n-1); % compute 2D FFT of the gray image
                fftI = fftshift(fftI); %centered FFT
                
                
                % Filter initialization
                h1 = ones(2*m-1, 2*n-1);
                h2 = ones(2*m-1, 2*n-1);
                h3 = ones(2*m-1, 2*n-1);
                
                for i = 1:2*m-1
                    for j =1:2*n-1
                        dist = sqrt((i-(m+1))^2 + (j-(n+1))^2);
                        h1(i,j) = exp(-dist^2 ./(2*app.whi^2));
                        h2(i,j) = exp(-dist^2 ./(2*app.wlow^2));
                        h3(i,j) = 1.0 - h2(i,j);
                        h3(i,j) = h1(i,j).*h3(i,j);
                    end
                end
                
                app.FTfilter = fftshift(fft2(h3)); % for PSD 
                
                filtered_image = h3.*fftI;
                filtered_image = ifftshift(filtered_image);
                filtered_image = ifft2(filtered_image, 2*m-1, 2*n-1);
                filtered_image = real(filtered_image(1:m, 1:n));
                
             app.FilteredImage.ImageSource = repmat(filtered_image, 1, 1, 3);
             
             BPFlinear = fftshift(fft2(h3(m+1, (n+1):size(h3,2)))); % in FT domain
                
                plot(app.ModuleH, abs(BPFlinear));
                plot(app.PhaseH, angle(BPFlinear));
             end
                
            app.parentApp.updateImageList(); 
                
           
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
              
       % Filter type selection
            if selectedTab == app.FiltertypeTab
                TypeFilterSelectionChanged(app, event);
            end
            
       % Change filter parameters
            if selectedTab == app.ParametersTab
                app.sigma  = app.sigmaSpinner.Value;
                app.wlow = app.LowerEditField.Value;
                app.whi = app.LowerEditField.Value;
                app.channel = app.channelSpinner.Value;
                app.FilterPowerSpectrum.ImageSource = repmat(app.filt, 1, 1, 3);
            end
            
       % Filter power spectrum view selection (abs / log)
            if selectedTab == app.PSDTab
                app.FilterPowerSpectrum.ImageSource = repmat(app.filt, 1, 1, 3);
                
                if app.LogarithmicButton.Value
                    fL = log(1 + abs(app.FTfilter));
                    app.filt = fL/max(fL(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(app.filt, 1, 1, 3);
                    app.Label.Text=strcat("PSD representation type: ", app.LogarithmicButton.Text);
                    
                end
                
                if app.AbsoluteButton.Value
                    fA = abs(app.FTfilter);
                    app.filt = fA/max(fA(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(app.filt, 1, 1, 3);
                    app.Label.Text=strcat("PSD representation type: ", app.AbsoluteButton.Text);
                end
            end    
            
            if selectedTab == app.ModulePhaseTab
                
                if app.LinearModule.Value
                    app.ModuleH.YScale = 'linear';
                else 
                    app.ModuleH.YScale = 'log';
                end
                
                if app.LinearPhase.Value
                    app.PhaseH.YScale = 'linear';
                else 
                    app.PhaseH.YScale = 'log';
                end
            end
            
            app.parentApp.updateImageList(); 
        end

        % Button pushed function: ExporttootherwindowButton
        function ExporttootherwindowButtonPushed(app, event)
            windowName = app.h.Name;
            
            fig = figure('Name',strcat(windowName," (Pixel interval matching)"));
            figure(fig);
            imshow(app.FilteredImage.ImageSource)
          
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            
            if app.LogarithmicButton.Value
                fL = log(1 + abs(app.gaussian));
                app.filt = fL/max(fL(:));
                app.FilterPowerSpectrum.ImageSource = repmat(app.filt, 1, 1, 3);
                app.Label.Text=strcat("PSD representation type: ", app.LogarithmicButton.Text);
            end
            
            if app.AbsoluteButton.Value
                fA = abs(app.gaussian);
                app.filt = fA/max(fA(:));
                app.FilterPowerSpectrum.ImageSource = repmat(app.filt, 1, 1, 3);
                app.Label.Text=strcat("PSD representation type: ", app.AbsoluteButton.Text);
            end
        end

        % Selection changed function: ModuleRep
        function ModuleRepSelectionChanged(app, event)
            
            if app.LinearModule.Value
                app.ModuleH.YScale = 'linear';
            else 
                app.ModuleH.YScale = 'log';
            end
         
        end

        % Selection changed function: PhaseRep
        function PhaseRepSelectionChanged(app, event)
            if app.LinearPhase.Value
                app.PhaseH.YScale = 'linear';
            else 
                app.PhaseH.YScale = 'log';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilteredImage = uiimage(app.UIFigure);
            app.OriginalImage = uiimage(app.UIFigure);
            app.FilterPowerSpectrum = uiimage(app.UIFigure);
            app.GaussianFilterG2Label = uilabel(app.UIFigure);
            app.GaussianFilterG2Label.Text = 'Gaussian Filter:  G(μ,σ^2)';
            app.OriginalimageLabel = uilabel(app.UIFigure);
            app.OriginalimageLabel.Text = 'Original image';
            app.FilterPowerSpectrumLabel = uilabel(app.UIFigure);
            app.FilterPowerSpectrumLabel.Text = 'Filter Power Spectrum';
            app.FilteredimageLabel = uilabel(app.UIFigure);
            app.FilteredimageLabel.Text = 'Filtered image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FiltertypeTab = uitab(app.TabGroup);
            app.FiltertypeTab.Title = 'Filter type';
            app.SelectTypeButtonGroup = uibuttongroup(app.FiltertypeTab);
            app.SelectTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeFilterSelectionChanged, true);
            app.SelectTypeButtonGroup.Title = 'Select a filter type';
            app.HighpassButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.HighpassButton.Text = 'Highpass';
            app.BandpassButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.BandpassButton.Text = 'Bandpass (specify wc frequencies)';
            app.BandpassButton.Value = true;
            app.LowpassButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.LowpassButton.Text = 'Lowpass';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.sigmaSpinnerLabel = uilabel(app.ParametersTab);
            app.sigmaSpinnerLabel.Text = 'sigma';
            app.sigmaSpinner = uispinner(app.ParametersTab);
            app.sigmaSpinner.LowerLimitInclusive = 'off';
            app.sigmaSpinner.Limits = [0 Inf];
            app.sigmaSpinner.Value = 1;
            app.LowerEditFieldLabel = uilabel(app.ParametersTab);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.ParametersTab, 'numeric');
            app.LowerEditField.Value = 30;
            app.HigherEditFieldLabel = uilabel(app.ParametersTab);
            app.HigherEditFieldLabel.Text = 'Higher';
            app.HigherEditField = uieditfield(app.ParametersTab, 'numeric');
            app.HigherEditField.Value = 120;
            app.CutofffrequenciesLabel = uilabel(app.ParametersTab);
            app.CutofffrequenciesLabel.Text = 'Cut-off frequencies';
            app.channelSpinnerLabel = uilabel(app.ParametersTab);
            app.channelSpinnerLabel.Text = 'channel';
            app.channelSpinner = uispinner(app.ParametersTab);
            app.channelSpinner.LowerLimitInclusive = 'off';
            app.channelSpinner.Limits = [0 Inf];
            app.channelSpinner.Value = 60;
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.TypeButtonGroup = uibuttongroup(app.PSDTab);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Select a representation';
            app.AbsoluteButton = uiradiobutton(app.TypeButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.AbsoluteButton.Value = true;
            app.LogarithmicButton = uiradiobutton(app.TypeButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.ModulePhaseTab = uitab(app.TabGroup);
            app.ModulePhaseTab.Title = 'Module | Phase';
            app.ModuleRep = uibuttongroup(app.ModulePhaseTab);
            app.ModuleRep.SelectionChangedFcn = createCallbackFcn(app, @ModuleRepSelectionChanged, true);
            app.ModuleRep.Title = 'Module representation';
            app.LinearModule = uiradiobutton(app.ModuleRep);
            app.LinearModule.Text = 'Linear';
            app.LinearModule.Value = true;
            app.LogarithmicModule = uiradiobutton(app.ModuleRep);
            app.LogarithmicModule.Text = 'Logarithmic';
            app.PhaseRep = uibuttongroup(app.ModulePhaseTab);
            app.PhaseRep.SelectionChangedFcn = createCallbackFcn(app, @PhaseRepSelectionChanged, true);
            app.PhaseRep.Title = 'Phase representation';
            app.LinearPhase = uiradiobutton(app.PhaseRep);
            app.LinearPhase.Text = 'Linear';
            app.LinearPhase.Value = true;
            app.LogarithmicPhase = uiradiobutton(app.PhaseRep);
            app.LogarithmicPhase.Text = 'Logarithmic';
            app.ExporttootherwindowButton = uibutton(app.UIFigure, 'push');
            app.ExporttootherwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttootherwindowButtonPushed, true);
            app.ExporttootherwindowButton.Text = 'Export to other window';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PSD representation type: ';
            app.ModuleH = uiaxes(app.UIFigure);
            title(app.ModuleH, 'Module Impulse response')
            xlabel(app.ModuleH, 'Frequency (Hz)')
            ylabel(app.ModuleH, 'Power spectrum (dB)')
            app.PhaseH = uiaxes(app.UIFigure);
            title(app.PhaseH, 'Phase Impulse response')
            xlabel(app.PhaseH, 'Frequency (Hz)')
            ylabel(app.PhaseH, 'Power spectrum (dB)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL9_GaussianFilterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end